classdef RSsourch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        explain_3                 matlab.ui.control.Button
        explain_2                 matlab.ui.control.Button
        quit                      matlab.ui.control.Button
        feedback                  matlab.ui.control.Button
        analyse                   matlab.ui.control.Button
        start                     matlab.ui.control.Button
        Button_33                 matlab.ui.control.Button
        Button_25                 matlab.ui.control.Button
        Button_17                 matlab.ui.control.Button
        Button_32                 matlab.ui.control.Button
        Button_24                 matlab.ui.control.Button
        Button_16                 matlab.ui.control.Button
        Button_31                 matlab.ui.control.Button
        Button_23                 matlab.ui.control.Button
        Button_15                 matlab.ui.control.Button
        Button_30                 matlab.ui.control.Button
        Button_22                 matlab.ui.control.Button
        Button_14                 matlab.ui.control.Button
        Button_29                 matlab.ui.control.Button
        Button_21                 matlab.ui.control.Button
        Button_13                 matlab.ui.control.Button
        Button_28                 matlab.ui.control.Button
        Button_20                 matlab.ui.control.Button
        Button_12                 matlab.ui.control.Button
        Button_27                 matlab.ui.control.Button
        Button_19                 matlab.ui.control.Button
        Button_11                 matlab.ui.control.Button
        Button_26                 matlab.ui.control.Button
        Button_18                 matlab.ui.control.Button
        Button_10                 matlab.ui.control.Button
        Button_9                  matlab.ui.control.Button
        Button_8                  matlab.ui.control.Button
        Button_7                  matlab.ui.control.Button
        Button_6                  matlab.ui.control.Button
        Button_5                  matlab.ui.control.Button
        DropDown                  matlab.ui.control.DropDown
        Label_3                   matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        explain_1                 matlab.ui.control.Button
        Switch                    matlab.ui.control.Switch
        EditField_8               matlab.ui.control.NumericEditField
        EditField_7               matlab.ui.control.NumericEditField
        EditField_6               matlab.ui.control.NumericEditField
        EditField_5               matlab.ui.control.NumericEditField
        EditField_4               matlab.ui.control.NumericEditField
        EditField_3               matlab.ui.control.NumericEditField
        EditField_2               matlab.ui.control.NumericEditField
        EditField_1               matlab.ui.control.NumericEditField
        Gist                      matlab.ui.control.CheckBox
        bianyuan                  matlab.ui.control.CheckBox
        sift                      matlab.ui.control.CheckBox
        lvboqitezheng             matlab.ui.control.CheckBox
        LBP                       matlab.ui.control.CheckBox
        cos                       matlab.ui.control.CheckBox
        huidugongshengjuzhen      matlab.ui.control.CheckBox
        huidutezheng              matlab.ui.control.CheckBox
        positionEditField         matlab.ui.control.NumericEditField
        positionEditFieldLabel    matlab.ui.control.Label
        situationEditField        matlab.ui.control.EditField
        situationEditFieldLabel   matlab.ui.control.Label
        pathEditField             matlab.ui.control.EditField
        pathEditFieldLabel        matlab.ui.control.Label
        nameformatEditField       matlab.ui.control.EditField
        nameformatEditFieldLabel  matlab.ui.control.Label
        position                  matlab.ui.control.Button
        read                      matlab.ui.control.Button
        choosepath                matlab.ui.control.Button
        choosename                matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        UIAxes_9                  matlab.ui.control.UIAxes
        UIAxes_8                  matlab.ui.control.UIAxes
        UIAxes_7                  matlab.ui.control.UIAxes
        UIAxes_6                  matlab.ui.control.UIAxes
        UIAxes_5                  matlab.ui.control.UIAxes
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes_1                  matlab.ui.control.UIAxes
        UIAxes_chosen             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        flag;% 点选反馈标志量
        falnumber;%
        
        filedir;%
        
        img;%全部图像文件
        number;%各个图像文件在文件夹中的位置
        yuan;%待检索原图像
        yuan_num;%待检索原图像在文件夹中的位置

        feature;%选择的特征提取方式的标志量
        dist;%选择的距离度量方式的标志量

        result;%
        index;%
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: explain_3
        function explain_3ButtonPushed(app, event)
            msgbox("这是一个基于内容的遥感影像场景检索app","关于程序","help");
        end

        % Button pushed function: explain_2
        function explain_2ButtonPushed(app, event)
            msgbox("这是一个基于用户点选反馈的精度分析，每选一个错误精度都会下降","关于精度评定","help");
        end

        % Button pushed function: quit
        function quitButtonPushed(app, event)
            choice=questdlg("是否要退出程序？","exit","yes","no","no");
            switch choice
                case "yes"
                    delete(app.UIFigure);
                    return;
                case "no"
                    return;
            end
        end

        % Button pushed function: choosename
        function choosenameButtonPushed(app, event)
            app.flag=0;
            app.falnumber=0;
            [rfilename,rpathname]=uigetfile({'*.tif';'*.jpg';'*.bmp';'*.*'},'待检索图像');
            if isequal(rfilename,0)||isequal(rpathname,0)
                errordlg("没有选中文件","出错啦");
                return;
            else
                %记录获取文件的名字和路径
                file=strcat(rpathname,rfilename);
            end
            app.yuan=imread(file);
            axis(app.UIAxes_chosen,'off');%隐藏坐标轴
            imshow(app.yuan,'Parent',app.UIAxes_chosen);
            app.nameformatEditField.Value=rfilename;
        end

        % Button pushed function: choosepath
        function choosepathButtonPushed(app, event)
            app.filedir=uigetdir('*.*','选择图像路径');
            app.filedir=strcat(app.filedir,'\');
            app.pathEditField.Value=app.filedir;
        end

        % Button pushed function: read
        function readButtonPushed(app, event)
            filePath1 = app.filedir;
            app.number=read_image(filePath1);
            app.img=read_image2(filePath1,app.number);
            app.situationEditField.Value="已成功读取全部图像文件";
        end

        % Button pushed function: position
        function positionButtonPushed(app, event)
            for i=1:app.number
                if isequal(rgb2gray(app.img{1,i}),rgb2gray(app.yuan))==1
                    app.yuan_num=i;
                    break
                else
                    continue
                end
            end
            app.positionEditField.Value=app.yuan_num;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,"on")
                app.feature=9;
            else
                return;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value=="L1"
                app.dist=1;
            elseif value=="L2"
                app.dist=2;
            else
                 msgbox('有问题无法进行下一步','对话框','warn');               
            end
        end

        % Value changed function: huidutezheng
        function huidutezhengValueChanged(app, event)
            value = app.huidutezheng.Value;
            if value==1
                app.feature=1;
            end
        end

        % Value changed function: huidugongshengjuzhen
        function huidugongshengjuzhenValueChanged(app, event)
            value = app.huidugongshengjuzhen.Value;
            if value==1
                app.feature=2;
            end
        end

        % Value changed function: cos
        function cosValueChanged(app, event)
            value = app.cos.Value;
            if value==1
                app.feature=3;
            end
        end

        % Value changed function: LBP
        function LBPValueChanged(app, event)
            value = app.LBP.Value;
            if value==1
                app.feature=4;
            end
        end

        % Value changed function: lvboqitezheng
        function lvboqitezhengValueChanged(app, event)
            value = app.lvboqitezheng.Value;
            if value==1
                app.feature=5;
            end
        end

        % Value changed function: sift
        function siftValueChanged(app, event)
            value = app.sift.Value;
            if value==1
                app.feature=6;
            end
        end

        % Value changed function: bianyuan
        function bianyuanValueChanged(app, event)
            value = app.bianyuan.Value;
            if value==1
                app.feature=7;
            end
        end

        % Value changed function: Gist
        function GistValueChanged(app, event)
            value = app.Gist.Value;
            if value==1
                app.feature=8;
            end
        end

        % Button pushed function: start
        function startButtonPushed(app, event)
           switch app.feature
                case 1
                    count=hist_feacture(app.number,app.img);
                    [app.result,app.index]=gray_sm(app.number,app.yuan_num,count,1);
                case 2
                    [app.result,app.index]=glcm_sm(app.img,app.yuan_num,app.number,app.dist);
                case 3
                    [app.result,app.index]=VC_sm(app.img,app.number,app.yuan_num);
                case 4
                    [app.result,app.index]=LBP_sm(app.number,app.img,app.yuan_num,1);
                case 5
                    [app.result,app.index]=my_filter_sm(app.img,app.number,app.yuan_num,app.dist);
                case 6
                    [app.result,app.index]=sift_sm(app.img,app.number,app.yuan_num,app.dist);
                case 7
                    [app.result,app.index]=prewii_sm(app.img,app.number,app.yuan_num,app.dist);
                case 8
                    [app.result,app.index]=GIST_sm(app.img,app.yuan_num,app.number,app.dist);
                case 9
                    msgbox('请在旁边的特征选取后边输入想要自己设计的权重（0~1之间）','对话框','help');
                    %小技巧，不用管前面画没画勾，后边输一个在0-1的之间的数就可可以实现多特征
                    p1=app.EditField_1.Value;
                    p2=app.EditField_2.Value;
                    p3=app.EditField_3.Value;
                    p4=app.EditField_4.Value;
                    p5=app.EditField_5.Value;
                    p6=app.EditField_6.Value;
                    p7=app.EditField_7.Value;
                    p8=app.EditField_8.Value;
                    count=hist_feacture(app.number,app.img);
                    [result1,~]=gray_sm(app.number,app.yuan_num,count,app.dist);
                    [result2,~]=glcm_sm(app.img,app.yuan_num,app.number,app.dist);
                    [result3,~]=VC_sm(app.img,app.number,app.yuan_num);
                    [result4,~]=LBP_sm(app.number,app.img,app.yuan_num,app.dist);
                    [result5,~]=my_filter_sm(app.img,app.number,app.yuan_num,app.dist);
                    [result6,~]=sift_sm(app.img,app.number,app.yuan_num,app.dist);
                    [result7,~]=prewii_sm(app.img,app.number,app.yuan_num,app.dist);
                    [result8,~]=GIST_sm(app.img,app.yuan_num,app.number,app.dist);
                    result1=p1*mapminmax(result1, 0, 1);
                    result2=p2*mapminmax(result2, 0, 1);
                    result3=p3*mapminmax(result3, 0, 1);
                    result4=p4*mapminmax(result4, 0, 1);
                    result5=p5*mapminmax(result5, 0, 1);
                    result6=p6*mapminmax(result6, 0, 1);
                    result7=p7*mapminmax(result7, 0, 1);
                    result8=p8*mapminmax(result8, 0, 1);
                    app.result= result1+result2+result3+result4+result5+result6+result7+result8;
                    [app.result,app.index]=sort(app.result,'ascend');
            end
            axis(app.UIAxes_1,"off");
            axis(app.UIAxes_2,"off");
            axis(app.UIAxes_3,"off");
            axis(app.UIAxes_4,"off");
            axis(app.UIAxes_5,"off");
            axis(app.UIAxes_6,"off");
            axis(app.UIAxes_7,"off");
            axis(app.UIAxes_8,"off");
            axis(app.UIAxes_9,"off");
            imshow(app.img{1,uint8(app.index(2))},'Parent',app.UIAxes_1);
            imshow(app.img{1,uint8(app.index(3))},'Parent',app.UIAxes_2);
            imshow(app.img{1,uint8(app.index(4))},'Parent',app.UIAxes_3);
            imshow(app.img{1,uint8(app.index(5))},'Parent',app.UIAxes_4);
            imshow(app.img{1,uint8(app.index(6))},'Parent',app.UIAxes_5);
            imshow(app.img{1,uint8(app.index(7))},'Parent',app.UIAxes_6);
            imshow(app.img{1,uint8(app.index(8))},'Parent',app.UIAxes_7);
            imshow(app.img{1,uint8(app.index(9))},'Parent',app.UIAxes_8);
            imshow(app.img{1,uint8(app.index(10))},'Parent',app.UIAxes_9);
        end

        % Button pushed function: feedback
        function feedbackButtonPushed(app, event)
            if app.flag==0
                app.flag=1;
            elseif app.flag==1
                app.flag=0;
            else
                msgbox('无法执行','对话框','warn');
            end
        end

        % Button pushed function: analyse
        function analyseButtonPushed(app, event)
            if app.falnumber==0
                msgbox('1','对话框','help');
            elseif app.falnumber>9
                app.falnumber=0;
            else
                temp=1-app.falnumber/9;
                output=string(temp);
                msgbox(output,'对话框','help');
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            figure;
            imhist(app.img{1,app.yuan_num});
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            figure;
            imhist(app.img{1,app.index(2)});
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            figure;
            imhist(app.img{1,app.index(3)});
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            figure;
            imhist(app.img{1,app.index(4)});
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            figure;
            imhist(app.img{1,app.index(5)});
        end

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
            figure;
            imhist(app.img{1,app.index(6)});
        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
            figure;
            imhist(app.img{1,app.index(7)});
        end

        % Button pushed function: Button_23
        function Button_23Pushed(app, event)
            figure;
            imhist(app.img{1,app.index(8)});
        end

        % Button pushed function: Button_24
        function Button_24Pushed(app, event)
            figure;
            imhist(app.img{1,app.index(9)});
        end

        % Button pushed function: Button_25
        function Button_25Pushed(app, event)
            figure;
            imhist(app.img{1,app.index(10)});
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            demoforHOG(app.img,app.yuan_num);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            demoforHOG(app.img,app.index(2));
        end

        % Button pushed function: Button_26
        function Button_26Pushed(app, event)
            demoforHOG(app.img,app.index(3));
        end

        % Button pushed function: Button_27
        function Button_27Pushed(app, event)
            demoforHOG(app.img,app.index(4));
        end

        % Button pushed function: Button_28
        function Button_28Pushed(app, event)
            demoforHOG(app.img,app.index(5));
        end

        % Button pushed function: Button_29
        function Button_29Pushed(app, event)
            demoforHOG(app.img,app.index(6));
        end

        % Button pushed function: Button_30
        function Button_30Pushed(app, event)
            demoforHOG(app.img,app.index(7));
        end

        % Button pushed function: Button_31
        function Button_31Pushed(app, event)
            demoforHOG(app.img,app.index(8));
        end

        % Button pushed function: Button_32
        function Button_32Pushed(app, event)
            demoforHOG(app.img,app.index(9));
        end

        % Button pushed function: Button_33
        function Button_33Pushed(app, event)
            demoforHOG(app.img,app.index(10));
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            if app.flag==1
                app.falnumber=app.falnumber+1;
                [app.img,app.number]=democo(app.img,app.index(2),app.number);
                if app.yuan_num>app.index(2)
                    yuan_number=app.yuan_num-1;
                else
                    yuan_number=app.yuan_num;
                end
                switch app.feature
                    case 1
                        count=hist_feacture(app.number,app.img);
                        [app.result,app.index]=gray_sm(app.number,yuan_number,count,1);
                    case 2
                        [app.result,app.index]=glcm_sm(app.img,yuan_number,app.number,app.dist);
                    case 3
                        [app.result,app.index]=VC_sm(app.img,app.number,yuan_number);
                    case 4
                        [app.result,app.index]=LBP_sm(app.number,app.img,yuan_number,1);
                    case 5
                        [app.result,app.index]=my_filter_sm(app.img,app.number,yuan_number,app.dist);
                    case 6
                        [app.result,app.index]=sift_sm(app.img,app.number,yuan_number,app.dist);
                    case 7
                        [app.result,app.index]=prewii_sm(app.img,app.number,yuan_number,app.dist);
                    case 8
                        [app.result,app.index]=GIST_sm(app.img,yuan_number,app.number,app.dist);
                    case 9
                        msgbox('请在旁边的特征选取后边输入想要自己设计的权重（0~1之间）','对话框','help');
                        %小技巧，不用管前面画没画勾，后边输一个在0-1的之间的数就可可以实现多特征
                        p1=app.EditField_1.Value;
                        p2=app.EditField_2.Value;
                        p3=app.EditField_3.Value;
                        p4=app.EditField_4.Value;
                        p5=app.EditField_5.Value;
                        p6=app.EditField_6.Value;
                        p7=app.EditField_7.Value;
                        p8=app.EditField_8.Value;
                        count=hist_feacture(app.number,app.img);
                        [result1,~]=gray_sm(app.number,yuan_number,count,app.dist);
                        [result2,~]=glcm_sm(app.img,yuan_number,app.number,app.dist);
                        [result3,~]=VC_sm(app.img,app.number,yuan_number);
                        [result4,~]=LBP_sm(app.number,app.img,yuan_number,app.dist);
                        [result5,~]=my_filter_sm(app.img,app.number,yuan_number,app.dist);
                        [result6,~]=sift_sm(app.img,app.number,yuan_number,app.dist);
                        [result7,~]=prewii_sm(app.img,app.number,yuan_number,app.dist);
                        [result8,~]=GIST_sm(app.img,yuan_number,app.number,app.dist);
                        result1=p1*mapminmax(result1, 0, 1);
                        result2=p2*mapminmax(result2, 0, 1);
                        result3=p3*mapminmax(result3, 0, 1);
                        result4=p4*mapminmax(result4, 0, 1);
                        result5=p5*mapminmax(result5, 0, 1);
                        result6=p6*mapminmax(result6, 0, 1);
                        result7=p7*mapminmax(result7, 0, 1);
                        result8=p8*mapminmax(result8, 0, 1);
                        app.result= result1+result2+result3+result4+result5+result6+result7+result8;
                        [app.result,app.index]=sort(app.result,'ascend');
                end
                axis(app.UIAxes_1,"off");
                axis(app.UIAxes_2,"off");
                axis(app.UIAxes_3,"off");
                axis(app.UIAxes_4,"off");
                axis(app.UIAxes_5,"off");
                axis(app.UIAxes_6,"off");
                axis(app.UIAxes_7,"off");
                axis(app.UIAxes_8,"off");
                axis(app.UIAxes_9,"off");
                imshow(app.img{1,uint8(app.index(2))},'Parent',app.UIAxes_1);
                imshow(app.img{1,uint8(app.index(3))},'Parent',app.UIAxes_2);
                imshow(app.img{1,uint8(app.index(4))},'Parent',app.UIAxes_3);
                imshow(app.img{1,uint8(app.index(5))},'Parent',app.UIAxes_4);
                imshow(app.img{1,uint8(app.index(6))},'Parent',app.UIAxes_5);
                imshow(app.img{1,uint8(app.index(7))},'Parent',app.UIAxes_6);
                imshow(app.img{1,uint8(app.index(8))},'Parent',app.UIAxes_7);
                imshow(app.img{1,uint8(app.index(9))},'Parent',app.UIAxes_8);
                imshow(app.img{1,uint8(app.index(10))},'Parent',app.UIAxes_9);
            else
                return;
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            if app.flag==1
                app.falnumber=app.falnumber+1;
                [app.img,app.number]=democo(app.img,app.index(3),app.number);
                if app.yuan_num>app.index(3)
                    yuan_number=app.yuan_num-1;
                else
                    yuan_number=app.yuan_num;
                end
                switch app.feature
                    case 1
                        count=hist_feacture(app.number,app.img);
                        [app.result,app.index]=gray_sm(app.number,yuan_number,count,1);
                    case 2
                        [app.result,app.index]=glcm_sm(app.img,yuan_number,app.number,app.dist);
                    case 3
                        [app.result,app.index]=VC_sm(app.img,app.number,yuan_number);
                    case 4
                        [app.result,app.index]=LBP_sm(app.number,app.img,yuan_number,1);
                    case 5
                        [app.result,app.index]=my_filter_sm(app.img,app.number,yuan_number,app.dist);
                    case 6
                        [app.result,app.index]=sift_sm(app.img,app.number,yuan_number,app.dist);
                    case 7
                        [app.result,app.index]=prewii_sm(app.img,app.number,yuan_number,app.dist);
                    case 8
                        [app.result,app.index]=GIST_sm(app.img,yuan_number,app.number,app.dist);
                    case 9
                        msgbox('请在旁边的特征选取后边输入想要自己设计的权重（0~1之间）','对话框','help');%小技巧，不用管前面画没画勾，后边输一个在0-1的之间的数就可可以实现多特征
                        p1=app.EditField_1.Value;
                        p2=app.EditField_2.Value;
                        p3=app.EditField_3.Value;
                        p4=app.EditField_4.Value;
                        p5=app.EditField_5.Value;
                        p6=app.EditField_6.Value;
                        p7=app.EditField_7.Value;
                        p8=app.EditField_8.Value;
                        count=hist_feacture(app.number,app.img);
                        [result1,~]=gray_sm(app.number,yuan_number,count,app.dist);
                        [result2,~]=glcm_sm(app.img,yuan_number,app.number,app.dist);
                        [result3,~]=VC_sm(app.img,app.number,yuan_number);
                        [result4,~]=LBP_sm(app.number,app.img,yuan_number,app.dist);
                        [result5,~]=my_filter_sm(app.img,app.number,yuan_number,app.dist);
                        [result6,~]=sift_sm(app.img,app.number,yuan_number,app.dist);
                        [result7,~]=prewii_sm(app.img,app.number,yuan_number,app.dist);
                        [result8,~]=GIST_sm(app.img,yuan_number,app.number,app.dist);
                        result1=p1*mapminmax(result1, 0, 1);
                        result2=p2*mapminmax(result2, 0, 1);
                        result3=p3*mapminmax(result3, 0, 1);
                        result4=p4*mapminmax(result4, 0, 1);
                        result5=p5*mapminmax(result5, 0, 1);
                        result6=p6*mapminmax(result6, 0, 1);
                        result7=p7*mapminmax(result7, 0, 1);
                        result8=p8*mapminmax(result8, 0, 1);
                        app.result= result1+result2+result3+result4+result5+result6+result7+result8;
                        [app.result,app.index]=sort(app.result,'ascend');
                end
                axis(app.UIAxes_1,"off");
                axis(app.UIAxes_2,"off");
                axis(app.UIAxes_3,"off");
                axis(app.UIAxes_4,"off");
                axis(app.UIAxes_5,"off");
                axis(app.UIAxes_6,"off");
                axis(app.UIAxes_7,"off");
                axis(app.UIAxes_8,"off");
                axis(app.UIAxes_9,"off");
                imshow(app.img{1,uint8(app.index(2))},'Parent',app.UIAxes_1);
                imshow(app.img{1,uint8(app.index(3))},'Parent',app.UIAxes_2);
                imshow(app.img{1,uint8(app.index(4))},'Parent',app.UIAxes_3);
                imshow(app.img{1,uint8(app.index(5))},'Parent',app.UIAxes_4);
                imshow(app.img{1,uint8(app.index(6))},'Parent',app.UIAxes_5);
                imshow(app.img{1,uint8(app.index(7))},'Parent',app.UIAxes_6);
                imshow(app.img{1,uint8(app.index(8))},'Parent',app.UIAxes_7);
                imshow(app.img{1,uint8(app.index(9))},'Parent',app.UIAxes_8);
                imshow(app.img{1,uint8(app.index(10))},'Parent',app.UIAxes_9);
            else
                return;
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            if app.flag==1
                app.falnumber=app.falnumber+1;
                [app.img,app.number]=democo(app.img,app.index(4),app.number);
                if app.yuan_num>app.index(4)
                    yuan_number=app.yuan_num-1;
                else
                    yuan_number=app.yuan_num;
                end
                switch app.feature
                    case 1
                        count=hist_feacture(app.number,app.img);
                        [app.result,app.index]=gray_sm(app.number,yuan_number,count,1);
                    case 2
                        [app.result,app.index]=glcm_sm(app.img,yuan_number,app.number,app.dist);
                    case 3
                        [app.result,app.index]=VC_sm(app.img,app.number,yuan_number);
                    case 4
                        [app.result,app.index]=LBP_sm(app.number,app.img,yuan_number,1);
                    case 5
                        [app.result,app.index]=my_filter_sm(app.img,app.number,yuan_number,app.dist);
                    case 6
                        [app.result,app.index]=sift_sm(app.img,app.number,yuan_number,app.dist);
                    case 7
                        [app.result,app.index]=prewii_sm(app.img,app.number,yuan_number,app.dist);
                    case 8
                        [app.result,app.index]=GIST_sm(app.img,yuan_number,app.number,app.dist);
                    case 9
                        msgbox('请在旁边的特征选取后边输入想要自己设计的权重（0~1之间）','对话框','help');%小技巧，不用管前面画没画勾，后边输一个在0-1的之间的数就可可以实现多特征
                        p1=app.EditField_1.Value;
                        p2=app.EditField_2.Value;
                        p3=app.EditField_3.Value;
                        p4=app.EditField_4.Value;
                        p5=app.EditField_5.Value;
                        p6=app.EditField_6.Value;
                        p7=app.EditField_7.Value;
                        p8=app.EditField_8.Value;
                        count=hist_feacture(app.number,app.img);
                        [result1,~]=gray_sm(app.number,yuan_number,count,app.dist);
                        [result2,~]=glcm_sm(app.img,yuan_number,app.number,app.dist);
                        [result3,~]=VC_sm(app.img,app.number,yuan_number);
                        [result4,~]=LBP_sm(app.number,app.img,yuan_number,app.dist);
                        [result5,~]=my_filter_sm(app.img,app.number,yuan_number,app.dist);
                        [result6,~]=sift_sm(app.img,app.number,yuan_number,app.dist);
                        [result7,~]=prewii_sm(app.img,app.number,yuan_number,app.dist);
                        [result8,~]=GIST_sm(app.img,yuan_number,app.number,app.dist);
                        result1=p1*mapminmax(result1, 0, 1);
                        result2=p2*mapminmax(result2, 0, 1);
                        result3=p3*mapminmax(result3, 0, 1);
                        result4=p4*mapminmax(result4, 0, 1);
                        result5=p5*mapminmax(result5, 0, 1);
                        result6=p6*mapminmax(result6, 0, 1);
                        result7=p7*mapminmax(result7, 0, 1);
                        result8=p8*mapminmax(result8, 0, 1);
                        app.result= result1+result2+result3+result4+result5+result6+result7+result8;
                        [app.result,app.index]=sort(app.result,'ascend');
                end
                axis(app.UIAxes_1,"off");
                axis(app.UIAxes_2,"off");
                axis(app.UIAxes_3,"off");
                axis(app.UIAxes_4,"off");
                axis(app.UIAxes_5,"off");
                axis(app.UIAxes_6,"off");
                axis(app.UIAxes_7,"off");
                axis(app.UIAxes_8,"off");
                axis(app.UIAxes_9,"off");
                imshow(app.img{1,uint8(app.index(2))},'Parent',app.UIAxes_1);
                imshow(app.img{1,uint8(app.index(3))},'Parent',app.UIAxes_2);
                imshow(app.img{1,uint8(app.index(4))},'Parent',app.UIAxes_3);
                imshow(app.img{1,uint8(app.index(5))},'Parent',app.UIAxes_4);
                imshow(app.img{1,uint8(app.index(6))},'Parent',app.UIAxes_5);
                imshow(app.img{1,uint8(app.index(7))},'Parent',app.UIAxes_6);
                imshow(app.img{1,uint8(app.index(8))},'Parent',app.UIAxes_7);
                imshow(app.img{1,uint8(app.index(9))},'Parent',app.UIAxes_8);
                imshow(app.img{1,uint8(app.index(10))},'Parent',app.UIAxes_9);
            else
                return;
            end
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            if app.flag==1
                app.falnumber=app.falnumber+1;
                [app.img,app.number]=democo(app.img,app.index(5),app.number);
                if app.yuan_num>app.index(5)
                    yuan_number=app.yuan_num-1;
                else
                    yuan_number=app.yuan_num;
                end
                switch app.feature
                    case 1
                        count=hist_feacture(app.number,app.img);
                        [app.result,app.index]=gray_sm(app.number,yuan_number,count,1);
                    case 2
                        [app.result,app.index]=glcm_sm(app.img,yuan_number,app.number,app.dist);
                    case 3
                        [app.result,app.index]=VC_sm(app.img,app.number,yuan_number);
                    case 4
                        [app.result,app.index]=LBP_sm(app.number,app.img,yuan_number,1);
                    case 5
                        [app.result,app.index]=my_filter_sm(app.img,app.number,yuan_number,app.dist);
                    case 6
                        [app.result,app.index]=sift_sm(app.img,app.number,yuan_number,app.dist);
                    case 7
                        [app.result,app.index]=prewii_sm(app.img,app.number,yuan_number,app.dist);
                    case 8
                        [app.result,app.index]=GIST_sm(app.img,yuan_number,app.number,app.dist);
                    case 9
                        msgbox('请在旁边的特征选取后边输入想要自己设计的权重（0~1之间）','对话框','help');%小技巧，不用管前面画没画勾，后边输一个在0-1的之间的数就可可以实现多特征
                        p1=app.EditField_1.Value;
                        p2=app.EditField_2.Value;
                        p3=app.EditField_3.Value;
                        p4=app.EditField_4.Value;
                        p5=app.EditField_5.Value;
                        p6=app.EditField_6.Value;
                        p7=app.EditField_7.Value;
                        p8=app.EditField_8.Value;
                        count=hist_feacture(app.number,app.img);
                        [result1,~]=gray_sm(app.number,yuan_number,count,app.dist);
                        [result2,~]=glcm_sm(app.img,yuan_number,app.number,app.dist);
                        [result3,~]=VC_sm(app.img,app.number,yuan_number);
                        [result4,~]=LBP_sm(app.number,app.img,yuan_number,app.dist);
                        [result5,~]=my_filter_sm(app.img,app.number,yuan_number,app.dist);
                        [result6,~]=sift_sm(app.img,app.number,yuan_number,app.dist);
                        [result7,~]=prewii_sm(app.img,app.number,yuan_number,app.dist);
                        [result8,~]=GIST_sm(app.img,yuan_number,app.number,app.dist);
                        result1=p1*mapminmax(result1, 0, 1);
                        result2=p2*mapminmax(result2, 0, 1);
                        result3=p3*mapminmax(result3, 0, 1);
                        result4=p4*mapminmax(result4, 0, 1);
                        result5=p5*mapminmax(result5, 0, 1);
                        result6=p6*mapminmax(result6, 0, 1);
                        result7=p7*mapminmax(result7, 0, 1);
                        result8=p8*mapminmax(result8, 0, 1);
                        app.result= result1+result2+result3+result4+result5+result6+result7+result8;
                        [app.result,app.index]=sort(app.result,'ascend');
                end
                axis(app.UIAxes_1,"off");
                axis(app.UIAxes_2,"off");
                axis(app.UIAxes_3,"off");
                axis(app.UIAxes_4,"off");
                axis(app.UIAxes_5,"off");
                axis(app.UIAxes_6,"off");
                axis(app.UIAxes_7,"off");
                axis(app.UIAxes_8,"off");
                axis(app.UIAxes_9,"off");
                imshow(app.img{1,uint8(app.index(2))},'Parent',app.UIAxes_1);
                imshow(app.img{1,uint8(app.index(3))},'Parent',app.UIAxes_2);
                imshow(app.img{1,uint8(app.index(4))},'Parent',app.UIAxes_3);
                imshow(app.img{1,uint8(app.index(5))},'Parent',app.UIAxes_4);
                imshow(app.img{1,uint8(app.index(6))},'Parent',app.UIAxes_5);
                imshow(app.img{1,uint8(app.index(7))},'Parent',app.UIAxes_6);
                imshow(app.img{1,uint8(app.index(8))},'Parent',app.UIAxes_7);
                imshow(app.img{1,uint8(app.index(9))},'Parent',app.UIAxes_8);
                imshow(app.img{1,uint8(app.index(10))},'Parent',app.UIAxes_9);
            else
                return;
            end
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            if app.flag==1
                app.falnumber=app.falnumber+1;
                [app.img,app.number]=democo(app.img,app.index(6),app.number);
                if app.yuan_num>app.index(6)
                    yuan_number=app.yuan_num-1;
                else
                    yuan_number=app.yuan_num;
                end
                switch app.feature
                    case 1
                        count=hist_feacture(app.number,app.img);
                        [app.result,app.index]=gray_sm(app.number,yuan_number,count,1);
                    case 2
                        [app.result,app.index]=glcm_sm(app.img,yuan_number,app.number,app.dist);
                    case 3
                        [app.result,app.index]=VC_sm(app.img,app.number,yuan_number);
                    case 4
                        [app.result,app.index]=LBP_sm(app.number,app.img,yuan_number,1);
                    case 5
                        [app.result,app.index]=my_filter_sm(app.img,app.number,yuan_number,app.dist);
                    case 6
                        [app.result,app.index]=sift_sm(app.img,app.number,yuan_number,app.dist);
                    case 7
                        [app.result,app.index]=prewii_sm(app.img,app.number,yuan_number,app.dist);
                    case 8
                        [app.result,app.index]=GIST_sm(app.img,yuan_number,app.number,app.dist);
                    case 9
                        msgbox('请在旁边的特征选取后边输入想要自己设计的权重（0~1之间）','对话框','help');%小技巧，不用管前面画没画勾，后边输一个在0-1的之间的数就可可以实现多特征
                        p1=app.EditField_1.Value;
                        p2=app.EditField_2.Value;
                        p3=app.EditField_3.Value;
                        p4=app.EditField_4.Value;
                        p5=app.EditField_5.Value;
                        p6=app.EditField_6.Value;
                        p7=app.EditField_7.Value;
                        p8=app.EditField_8.Value;
                        count=hist_feacture(app.number,app.img);
                        [result1,~]=gray_sm(app.number,yuan_number,count,app.dist);
                        [result2,~]=glcm_sm(app.img,yuan_number,app.number,app.dist);
                        [result3,~]=VC_sm(app.img,app.number,yuan_number);
                        [result4,~]=LBP_sm(app.number,app.img,yuan_number,app.dist);
                        [result5,~]=my_filter_sm(app.img,app.number,yuan_number,app.dist);
                        [result6,~]=sift_sm(app.img,app.number,yuan_number,app.dist);
                        [result7,~]=prewii_sm(app.img,app.number,yuan_number,app.dist);
                        [result8,~]=GIST_sm(app.img,yuan_number,app.number,app.dist);
                        result1=p1*mapminmax(result1, 0, 1);
                        result2=p2*mapminmax(result2, 0, 1);
                        result3=p3*mapminmax(result3, 0, 1);
                        result4=p4*mapminmax(result4, 0, 1);
                        result5=p5*mapminmax(result5, 0, 1);
                        result6=p6*mapminmax(result6, 0, 1);
                        result7=p7*mapminmax(result7, 0, 1);
                        result8=p8*mapminmax(result8, 0, 1);
                        app.result= result1+result2+result3+result4+result5+result6+result7+result8;
                        [app.result,app.index]=sort(app.result,'ascend');
                end
                axis(app.UIAxes_1,"off");
                axis(app.UIAxes_2,"off");
                axis(app.UIAxes_3,"off");
                axis(app.UIAxes_4,"off");
                axis(app.UIAxes_5,"off");
                axis(app.UIAxes_6,"off");
                axis(app.UIAxes_7,"off");
                axis(app.UIAxes_8,"off");
                axis(app.UIAxes_9,"off");
                imshow(app.img{1,uint8(app.index(2))},'Parent',app.UIAxes_1);
                imshow(app.img{1,uint8(app.index(3))},'Parent',app.UIAxes_2);
                imshow(app.img{1,uint8(app.index(4))},'Parent',app.UIAxes_3);
                imshow(app.img{1,uint8(app.index(5))},'Parent',app.UIAxes_4);
                imshow(app.img{1,uint8(app.index(6))},'Parent',app.UIAxes_5);
                imshow(app.img{1,uint8(app.index(7))},'Parent',app.UIAxes_6);
                imshow(app.img{1,uint8(app.index(8))},'Parent',app.UIAxes_7);
                imshow(app.img{1,uint8(app.index(9))},'Parent',app.UIAxes_8);
                imshow(app.img{1,uint8(app.index(10))},'Parent',app.UIAxes_9);
            else
                return;
            end
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            if app.flag==1
                app.falnumber=app.falnumber+1;
                [app.img,app.number]=democo(app.img,app.index(7),app.number);
                if app.yuan_num>app.index(7)
                    yuan_number=app.yuan_num-1;
                else
                    yuan_number=app.yuan_num;
                end
                switch app.feature
                    case 1
                        count=hist_feacture(app.number,app.img);
                        [app.result,app.index]=gray_sm(app.number,yuan_number,count,1);
                    case 2
                        [app.result,app.index]=glcm_sm(app.img,yuan_number,app.number,app.dist);
                    case 3
                        [app.result,app.index]=VC_sm(app.img,app.number,yuan_number);
                    case 4
                        [app.result,app.index]=LBP_sm(app.number,app.img,yuan_number,1);
                    case 5
                        [app.result,app.index]=my_filter_sm(app.img,app.number,yuan_number,app.dist);
                    case 6
                        [app.result,app.index]=sift_sm(app.img,app.number,yuan_number,app.dist);
                    case 7
                        [app.result,app.index]=prewii_sm(app.img,app.number,yuan_number,app.dist);
                    case 8
                        [app.result,app.index]=GIST_sm(app.img,yuan_number,app.number,app.dist);
                    case 9
                        msgbox('请在旁边的特征选取后边输入想要自己设计的权重（0~1之间）','对话框','help');%小技巧，不用管前面画没画勾，后边输一个在0-1的之间的数就可可以实现多特征
                        p1=app.EditField_1.Value;
                        p2=app.EditField_2.Value;
                        p3=app.EditField_3.Value;
                        p4=app.EditField_4.Value;
                        p5=app.EditField_5.Value;
                        p6=app.EditField_6.Value;
                        p7=app.EditField_7.Value;
                        p8=app.EditField_8.Value;
                        count=hist_feacture(app.number,app.img);
                        [result1,~]=gray_sm(app.number,yuan_number,count,app.dist);
                        [result2,~]=glcm_sm(app.img,yuan_number,app.number,app.dist);
                        [result3,~]=VC_sm(app.img,app.number,yuan_number);
                        [result4,~]=LBP_sm(app.number,app.img,yuan_number,app.dist);
                        [result5,~]=my_filter_sm(app.img,app.number,yuan_number,app.dist);
                        [result6,~]=sift_sm(app.img,app.number,yuan_number,app.dist);
                        [result7,~]=prewii_sm(app.img,app.number,yuan_number,app.dist);
                        [result8,~]=GIST_sm(app.img,yuan_number,app.number,app.dist);
                        result1=p1*mapminmax(result1, 0, 1);
                        result2=p2*mapminmax(result2, 0, 1);
                        result3=p3*mapminmax(result3, 0, 1);
                        result4=p4*mapminmax(result4, 0, 1);
                        result5=p5*mapminmax(result5, 0, 1);
                        result6=p6*mapminmax(result6, 0, 1);
                        result7=p7*mapminmax(result7, 0, 1);
                        result8=p8*mapminmax(result8, 0, 1);
                        app.result= result1+result2+result3+result4+result5+result6+result7+result8;
                        [app.result,app.index]=sort(app.result,'ascend');
                end
                axis(app.UIAxes_1,"off");
                axis(app.UIAxes_2,"off");
                axis(app.UIAxes_3,"off");
                axis(app.UIAxes_4,"off");
                axis(app.UIAxes_5,"off");
                axis(app.UIAxes_6,"off");
                axis(app.UIAxes_7,"off");
                axis(app.UIAxes_8,"off");
                axis(app.UIAxes_9,"off");
                imshow(app.img{1,uint8(app.index(2))},'Parent',app.UIAxes_1);
                imshow(app.img{1,uint8(app.index(3))},'Parent',app.UIAxes_2);
                imshow(app.img{1,uint8(app.index(4))},'Parent',app.UIAxes_3);
                imshow(app.img{1,uint8(app.index(5))},'Parent',app.UIAxes_4);
                imshow(app.img{1,uint8(app.index(6))},'Parent',app.UIAxes_5);
                imshow(app.img{1,uint8(app.index(7))},'Parent',app.UIAxes_6);
                imshow(app.img{1,uint8(app.index(8))},'Parent',app.UIAxes_7);
                imshow(app.img{1,uint8(app.index(9))},'Parent',app.UIAxes_8);
                imshow(app.img{1,uint8(app.index(10))},'Parent',app.UIAxes_9);
            else
                return;
            end
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            if app.flag==1
                app.falnumber=app.falnumber+1;
                [app.img,app.number]=democo(app.img,app.index(8),app.number);
                if app.yuan_num>app.index(8)
                    yuan_number=app.yuan_num-1;
                else
                    yuan_number=app.yuan_num;
                end
                switch app.feature
                    case 1
                        count=hist_feacture(app.number,app.img);
                        [app.result,app.index]=gray_sm(app.number,yuan_number,count,1);
                    case 2
                        [app.result,app.index]=glcm_sm(app.img,yuan_number,app.number,app.dist);
                    case 3
                        [app.result,app.index]=VC_sm(app.img,app.number,yuan_number);
                    case 4
                        [app.result,app.index]=LBP_sm(app.number,app.img,yuan_number,1);
                    case 5
                        [app.result,app.index]=my_filter_sm(app.img,app.number,yuan_number,app.dist);
                    case 6
                        [app.result,app.index]=sift_sm(app.img,app.number,yuan_number,app.dist);
                    case 7
                        [app.result,app.index]=prewii_sm(app.img,app.number,yuan_number,app.dist);
                    case 8
                        [app.result,app.index]=GIST_sm(app.img,yuan_number,app.number,app.dist);
                    case 9
                        msgbox('请在旁边的特征选取后边输入想要自己设计的权重（0~1之间）','对话框','help');%小技巧，不用管前面画没画勾，后边输一个在0-1的之间的数就可可以实现多特征
                        p1=app.EditField_1.Value;
                        p2=app.EditField_2.Value;
                        p3=app.EditField_3.Value;
                        p4=app.EditField_4.Value;
                        p5=app.EditField_5.Value;
                        p6=app.EditField_6.Value;
                        p7=app.EditField_7.Value;
                        p8=app.EditField_8.Value;
                        count=hist_feacture(app.number,app.img);
                        [result1,~]=gray_sm(app.number,yuan_number,count,app.dist);
                        [result2,~]=glcm_sm(app.img,yuan_number,app.number,app.dist);
                        [result3,~]=VC_sm(app.img,app.number,yuan_number);
                        [result4,~]=LBP_sm(app.number,app.img,yuan_number,app.dist);
                        [result5,~]=my_filter_sm(app.img,app.number,yuan_number,app.dist);
                        [result6,~]=sift_sm(app.img,app.number,yuan_number,app.dist);
                        [result7,~]=prewii_sm(app.img,app.number,yuan_number,app.dist);
                        [result8,~]=GIST_sm(app.img,yuan_number,app.number,app.dist);
                        result1=p1*mapminmax(result1, 0, 1);
                        result2=p2*mapminmax(result2, 0, 1);
                        result3=p3*mapminmax(result3, 0, 1);
                        result4=p4*mapminmax(result4, 0, 1);
                        result5=p5*mapminmax(result5, 0, 1);
                        result6=p6*mapminmax(result6, 0, 1);
                        result7=p7*mapminmax(result7, 0, 1);
                        result8=p8*mapminmax(result8, 0, 1);
                        app.result= result1+result2+result3+result4+result5+result6+result7+result8;
                        [app.result,app.index]=sort(app.result,'ascend');
                end
                axis(app.UIAxes_1,"off");
                axis(app.UIAxes_2,"off");
                axis(app.UIAxes_3,"off");
                axis(app.UIAxes_4,"off");
                axis(app.UIAxes_5,"off");
                axis(app.UIAxes_6,"off");
                axis(app.UIAxes_7,"off");
                axis(app.UIAxes_8,"off");
                axis(app.UIAxes_9,"off");
                imshow(app.img{1,uint8(app.index(2))},'Parent',app.UIAxes_1);
                imshow(app.img{1,uint8(app.index(3))},'Parent',app.UIAxes_2);
                imshow(app.img{1,uint8(app.index(4))},'Parent',app.UIAxes_3);
                imshow(app.img{1,uint8(app.index(5))},'Parent',app.UIAxes_4);
                imshow(app.img{1,uint8(app.index(6))},'Parent',app.UIAxes_5);
                imshow(app.img{1,uint8(app.index(7))},'Parent',app.UIAxes_6);
                imshow(app.img{1,uint8(app.index(8))},'Parent',app.UIAxes_7);
                imshow(app.img{1,uint8(app.index(9))},'Parent',app.UIAxes_8);
                imshow(app.img{1,uint8(app.index(10))},'Parent',app.UIAxes_9);
            else
                return;
            end
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            if app.flag==1
                app.falnumber=app.falnumber+1;
                [app.img,app.number]=democo(app.img,app.index(9),app.number);
                if app.yuan_num>app.index(9)
                    yuan_number=app.yuan_num-1;
                else
                    yuan_number=app.yuan_num;
                end
                switch app.feature
                    case 1
                        count=hist_feacture(app.number,app.img);
                        [app.result,app.index]=gray_sm(app.number,yuan_number,count,1);
                    case 2
                        [app.result,app.index]=glcm_sm(app.img,yuan_number,app.number,app.dist);
                    case 3
                        [app.result,app.index]=VC_sm(app.img,app.number,yuan_number);
                    case 4
                        [app.result,app.index]=LBP_sm(app.number,app.img,yuan_number,1);
                    case 5
                        [app.result,app.index]=my_filter_sm(app.img,app.number,yuan_number,app.dist);
                    case 6
                        [app.result,app.index]=sift_sm(app.img,app.number,yuan_number,app.dist);
                    case 7
                        [app.result,app.index]=prewii_sm(app.img,app.number,yuan_number,app.dist);
                    case 8
                        [app.result,app.index]=GIST_sm(app.img,yuan_number,app.number,app.dist);
                    case 9
                        msgbox('请在旁边的特征选取后边输入想要自己设计的权重（0~1之间）','对话框','help');%小技巧，不用管前面画没画勾，后边输一个在0-1的之间的数就可可以实现多特征
                        p1=app.EditField_1.Value;
                        p2=app.EditField_2.Value;
                        p3=app.EditField_3.Value;
                        p4=app.EditField_4.Value;
                        p5=app.EditField_5.Value;
                        p6=app.EditField_6.Value;
                        p7=app.EditField_7.Value;
                        p8=app.EditField_8.Value;
                        count=hist_feacture(app.number,app.img);
                        [result1,~]=gray_sm(app.number,yuan_number,count,app.dist);
                        [result2,~]=glcm_sm(app.img,yuan_number,app.number,app.dist);
                        [result3,~]=VC_sm(app.img,app.number,yuan_number);
                        [result4,~]=LBP_sm(app.number,app.img,yuan_number,app.dist);
                        [result5,~]=my_filter_sm(app.img,app.number,yuan_number,app.dist);
                        [result6,~]=sift_sm(app.img,app.number,yuan_number,app.dist);
                        [result7,~]=prewii_sm(app.img,app.number,yuan_number,app.dist);
                        [result8,~]=GIST_sm(app.img,yuan_number,app.number,app.dist);
                        result1=p1*mapminmax(result1, 0, 1);
                        result2=p2*mapminmax(result2, 0, 1);
                        result3=p3*mapminmax(result3, 0, 1);
                        result4=p4*mapminmax(result4, 0, 1);
                        result5=p5*mapminmax(result5, 0, 1);
                        result6=p6*mapminmax(result6, 0, 1);
                        result7=p7*mapminmax(result7, 0, 1);
                        result8=p8*mapminmax(result8, 0, 1);
                        app.result= result1+result2+result3+result4+result5+result6+result7+result8;
                        [app.result,app.index]=sort(app.result,'ascend');
                end
                axis(app.UIAxes_1,"off");
                axis(app.UIAxes_2,"off");
                axis(app.UIAxes_3,"off");
                axis(app.UIAxes_4,"off");
                axis(app.UIAxes_5,"off");
                axis(app.UIAxes_6,"off");
                axis(app.UIAxes_7,"off");
                axis(app.UIAxes_8,"off");
                axis(app.UIAxes_9,"off");
                imshow(app.img{1,uint8(app.index(2))},'Parent',app.UIAxes_1);
                imshow(app.img{1,uint8(app.index(3))},'Parent',app.UIAxes_2);
                imshow(app.img{1,uint8(app.index(4))},'Parent',app.UIAxes_3);
                imshow(app.img{1,uint8(app.index(5))},'Parent',app.UIAxes_4);
                imshow(app.img{1,uint8(app.index(6))},'Parent',app.UIAxes_5);
                imshow(app.img{1,uint8(app.index(7))},'Parent',app.UIAxes_6);
                imshow(app.img{1,uint8(app.index(8))},'Parent',app.UIAxes_7);
                imshow(app.img{1,uint8(app.index(9))},'Parent',app.UIAxes_8);
                imshow(app.img{1,uint8(app.index(10))},'Parent',app.UIAxes_9);
            else
                return;
            end
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            if app.flag==1
                app.falnumber=app.falnumber+1;
                [app.img,app.number]=democo(app.img,app.index(10),app.number);
                if app.yuan_num>app.index(10)
                    yuan_number=app.yuan_num-1;
                else
                    yuan_number=app.yuan_num;
                end
                switch app.feature
                    case 1
                        count=hist_feacture(app.number,app.img);
                        [app.result,app.index]=gray_sm(app.number,yuan_number,count,1);
                    case 2
                        [app.result,app.index]=glcm_sm(app.img,yuan_number,app.number,app.dist);
                    case 3
                        [app.result,app.index]=VC_sm(app.img,app.number,yuan_number);
                    case 4
                        [app.result,app.index]=LBP_sm(app.number,app.img,yuan_number,1);
                    case 5
                        [app.result,app.index]=my_filter_sm(app.img,app.number,yuan_number,app.dist);
                    case 6
                        [app.result,app.index]=sift_sm(app.img,app.number,yuan_number,app.dist);
                    case 7
                        [app.result,app.index]=prewii_sm(app.img,app.number,yuan_number,app.dist);
                    case 8
                        [app.result,app.index]=GIST_sm(app.img,yuan_number,app.number,app.dist);
                    case 9
                        msgbox('请在旁边的特征选取后边输入想要自己设计的权重（0~1之间）','对话框','help');%小技巧，不用管前面画没画勾，后边输一个在0-1的之间的数就可可以实现多特征
                        p1=app.EditField_1.Value;
                        p2=app.EditField_2.Value;
                        p3=app.EditField_3.Value;
                        p4=app.EditField_4.Value;
                        p5=app.EditField_5.Value;
                        p6=app.EditField_6.Value;
                        p7=app.EditField_7.Value;
                        p8=app.EditField_8.Value;
                        count=hist_feacture(app.number,app.img);
                        [result1,~]=gray_sm(app.number,yuan_number,count,app.dist);
                        [result2,~]=glcm_sm(app.img,yuan_number,app.number,app.dist);
                        [result3,~]=VC_sm(app.img,app.number,yuan_number);
                        [result4,~]=LBP_sm(app.number,app.img,yuan_number,app.dist);
                        [result5,~]=my_filter_sm(app.img,app.number,yuan_number,app.dist);
                        [result6,~]=sift_sm(app.img,app.number,yuan_number,app.dist);
                        [result7,~]=prewii_sm(app.img,app.number,yuan_number,app.dist);
                        [result8,~]=GIST_sm(app.img,yuan_number,app.number,app.dist);
                        result1=p1*mapminmax(result1, 0, 1);
                        result2=p2*mapminmax(result2, 0, 1);
                        result3=p3*mapminmax(result3, 0, 1);
                        result4=p4*mapminmax(result4, 0, 1);
                        result5=p5*mapminmax(result5, 0, 1);
                        result6=p6*mapminmax(result6, 0, 1);
                        result7=p7*mapminmax(result7, 0, 1);
                        result8=p8*mapminmax(result8, 0, 1);
                        app.result= result1+result2+result3+result4+result5+result6+result7+result8;
                        [app.result,app.index]=sort(app.result,'ascend');
                end
                axis(app.UIAxes_1,"off");
                axis(app.UIAxes_2,"off");
                axis(app.UIAxes_3,"off");
                axis(app.UIAxes_4,"off");
                axis(app.UIAxes_5,"off");
                axis(app.UIAxes_6,"off");
                axis(app.UIAxes_7,"off");
                axis(app.UIAxes_8,"off");
                axis(app.UIAxes_9,"off");
                imshow(app.img{1,uint8(app.index(2))},'Parent',app.UIAxes_1);
                imshow(app.img{1,uint8(app.index(3))},'Parent',app.UIAxes_2);
                imshow(app.img{1,uint8(app.index(4))},'Parent',app.UIAxes_3);
                imshow(app.img{1,uint8(app.index(5))},'Parent',app.UIAxes_4);
                imshow(app.img{1,uint8(app.index(6))},'Parent',app.UIAxes_5);
                imshow(app.img{1,uint8(app.index(7))},'Parent',app.UIAxes_6);
                imshow(app.img{1,uint8(app.index(8))},'Parent',app.UIAxes_7);
                imshow(app.img{1,uint8(app.index(9))},'Parent',app.UIAxes_8);
                imshow(app.img{1,uint8(app.index(10))},'Parent',app.UIAxes_9);
            else
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '基于内容的遥感影像场景检索';
            app.UIAxes_chosen = uiaxes(app.UIFigure);
            title(app.UIAxes_chosen, '待检测影像')
            app.UIAxes_1 = uiaxes(app.UIFigure);
            title(app.UIAxes_1, '1')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '2')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '3')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, '4')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, '5')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, '6')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, '7')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, '8')
            app.UIAxes_9 = uiaxes(app.UIFigure);
            title(app.UIAxes_9, '9')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '图像选取与导入';
            app.choosename = uibutton(app.UIFigure, 'push');
            app.choosename.ButtonPushedFcn = createCallbackFcn(app, @choosenameButtonPushed, true);
            app.choosename.Text = '选择待检测影像';
            app.choosepath = uibutton(app.UIFigure, 'push');
            app.choosepath.ButtonPushedFcn = createCallbackFcn(app, @choosepathButtonPushed, true);
            app.choosepath.Text = '选择待检测影像路径';
            app.read = uibutton(app.UIFigure, 'push');
            app.read.ButtonPushedFcn = createCallbackFcn(app, @readButtonPushed, true);
            app.read.Text = '读取影像文件';
            app.position = uibutton(app.UIFigure, 'push');
            app.position.ButtonPushedFcn = createCallbackFcn(app, @positionButtonPushed, true);
            app.position.Text = '对比影像在文件夹中位置';
            app.nameformatEditFieldLabel = uilabel(app.UIFigure);
            app.nameformatEditFieldLabel.Text = 'name.format';
            app.nameformatEditField = uieditfield(app.UIFigure, 'text');
            app.pathEditFieldLabel = uilabel(app.UIFigure);
            app.pathEditFieldLabel.Text = 'path';
            app.pathEditField = uieditfield(app.UIFigure, 'text');
            app.situationEditFieldLabel = uilabel(app.UIFigure);
            app.situationEditFieldLabel.Text = 'situation';
            app.situationEditField = uieditfield(app.UIFigure, 'text');
            app.positionEditFieldLabel = uilabel(app.UIFigure);
            app.positionEditFieldLabel.Text = 'position';
            app.positionEditField = uieditfield(app.UIFigure, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '特征选取';
            app.huidutezheng = uicheckbox(app.Panel);
            app.huidutezheng.ValueChangedFcn = createCallbackFcn(app, @huidutezhengValueChanged, true);
            app.huidutezheng.Text = '灰度特征';
            app.huidugongshengjuzhen = uicheckbox(app.Panel);
            app.huidugongshengjuzhen.ValueChangedFcn = createCallbackFcn(app, @huidugongshengjuzhenValueChanged, true);
            app.huidugongshengjuzhen.Text = '灰度共生矩阵';
            app.cos = uicheckbox(app.Panel);
            app.cos.ValueChangedFcn = createCallbackFcn(app, @cosValueChanged, true);
            app.cos.Text = '余弦相似度';
            app.LBP = uicheckbox(app.Panel);
            app.LBP.ValueChangedFcn = createCallbackFcn(app, @LBPValueChanged, true);
            app.LBP.Text = 'LBP';
            app.lvboqitezheng = uicheckbox(app.Panel);
            app.lvboqitezheng.ValueChangedFcn = createCallbackFcn(app, @lvboqitezhengValueChanged, true);
            app.lvboqitezheng.Text = '滤波器特征';
            app.sift = uicheckbox(app.Panel);
            app.sift.ValueChangedFcn = createCallbackFcn(app, @siftValueChanged, true);
            app.sift.Text = 'sift特征';
            app.bianyuan = uicheckbox(app.Panel);
            app.bianyuan.ValueChangedFcn = createCallbackFcn(app, @bianyuanValueChanged, true);
            app.bianyuan.Text = '边缘特征';
            app.Gist = uicheckbox(app.Panel);
            app.Gist.ValueChangedFcn = createCallbackFcn(app, @GistValueChanged, true);
            app.Gist.Text = 'Gist特征';
            app.EditField_1 = uieditfield(app.Panel, 'numeric');
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_4 = uieditfield(app.Panel, 'numeric');
            app.EditField_5 = uieditfield(app.Panel, 'numeric');
            app.EditField_6 = uieditfield(app.Panel, 'numeric');
            app.EditField_7 = uieditfield(app.Panel, 'numeric');
            app.EditField_8 = uieditfield(app.Panel, 'numeric');
            app.Switch = uiswitch(app.Panel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.explain_1 = uibutton(app.Panel, 'push');
            app.explain_1.Tooltip = {'👆多特征运算的开关按钮'};
            app.explain_1.Text = '多特征运算';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '特征选择与距离度量方式';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '特征距离比较方式';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'L1', 'L2'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'L1';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '直方图';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '梯度方向直方图';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '错误';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '直方图';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '梯度方向直方图';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '错误';
            app.Button_18 = uibutton(app.UIFigure, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '直方图';
            app.Button_26 = uibutton(app.UIFigure, 'push');
            app.Button_26.ButtonPushedFcn = createCallbackFcn(app, @Button_26Pushed, true);
            app.Button_26.Text = '梯度方向直方图';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '错误';
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '直方图';
            app.Button_27 = uibutton(app.UIFigure, 'push');
            app.Button_27.ButtonPushedFcn = createCallbackFcn(app, @Button_27Pushed, true);
            app.Button_27.Text = '梯度方向直方图';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '错误';
            app.Button_20 = uibutton(app.UIFigure, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '直方图';
            app.Button_28 = uibutton(app.UIFigure, 'push');
            app.Button_28.ButtonPushedFcn = createCallbackFcn(app, @Button_28Pushed, true);
            app.Button_28.Text = '梯度方向直方图';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '错误';
            app.Button_21 = uibutton(app.UIFigure, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Text = '直方图';
            app.Button_29 = uibutton(app.UIFigure, 'push');
            app.Button_29.ButtonPushedFcn = createCallbackFcn(app, @Button_29Pushed, true);
            app.Button_29.Text = '梯度方向直方图';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '错误';
            app.Button_22 = uibutton(app.UIFigure, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Text = '直方图';
            app.Button_30 = uibutton(app.UIFigure, 'push');
            app.Button_30.ButtonPushedFcn = createCallbackFcn(app, @Button_30Pushed, true);
            app.Button_30.Text = '梯度方向直方图';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '错误';
            app.Button_23 = uibutton(app.UIFigure, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @Button_23Pushed, true);
            app.Button_23.Text = '直方图';
            app.Button_31 = uibutton(app.UIFigure, 'push');
            app.Button_31.ButtonPushedFcn = createCallbackFcn(app, @Button_31Pushed, true);
            app.Button_31.Text = '梯度方向直方图';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '错误';
            app.Button_24 = uibutton(app.UIFigure, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @Button_24Pushed, true);
            app.Button_24.Text = '直方图';
            app.Button_32 = uibutton(app.UIFigure, 'push');
            app.Button_32.ButtonPushedFcn = createCallbackFcn(app, @Button_32Pushed, true);
            app.Button_32.Text = '梯度方向直方图';
            app.Button_17 = uibutton(app.UIFigure, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '错误';
            app.Button_25 = uibutton(app.UIFigure, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @Button_25Pushed, true);
            app.Button_25.Text = '直方图';
            app.Button_33 = uibutton(app.UIFigure, 'push');
            app.Button_33.ButtonPushedFcn = createCallbackFcn(app, @Button_33Pushed, true);
            app.Button_33.Text = '梯度方向直方图';
            app.start = uibutton(app.UIFigure, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = '开始检索';
            app.analyse = uibutton(app.UIFigure, 'push');
            app.analyse.ButtonPushedFcn = createCallbackFcn(app, @analyseButtonPushed, true);
            app.analyse.Text = '精度分析';
            app.feedback = uibutton(app.UIFigure, 'push');
            app.feedback.ButtonPushedFcn = createCallbackFcn(app, @feedbackButtonPushed, true);
            app.feedback.Text = '错误反馈';
            app.quit = uibutton(app.UIFigure, 'push');
            app.quit.ButtonPushedFcn = createCallbackFcn(app, @quitButtonPushed, true);
            app.quit.Tooltip = {'点此可以关闭程序'};
            app.quit.Text = '退出程序';
            app.explain_2 = uibutton(app.UIFigure, 'push');
            app.explain_2.ButtonPushedFcn = createCallbackFcn(app, @explain_2ButtonPushed, true);
            app.explain_2.Tooltip = {'点此了解精度分析按钮说明'};
            app.explain_2.Text = '关于精度分析';
            app.explain_3 = uibutton(app.UIFigure, 'push');
            app.explain_3.ButtonPushedFcn = createCallbackFcn(app, @explain_3ButtonPushed, true);
            app.explain_3.Tooltip = {'点此了解程序内容'};
            app.explain_3.Text = '关于程序';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RSsourch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end